classdef imageThresholdCounter1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        gridRotation                    matlab.ui.control.NumericEditField
        RotationdegreesLabel            matlab.ui.control.Label
        updaterClearTableButton         matlab.ui.control.Button
        RowtoDeleteEditField_2Label     matlab.ui.control.Label
        updaterRowtoDeleteEditField     matlab.ui.control.NumericEditField
        updaterDeleteRowButton          matlab.ui.control.Button
        updaterAddtoTableButton         matlab.ui.control.Button
        updaterTable                    matlab.ui.control.Table
        transparencyValue               matlab.ui.control.NumericEditField
        gridRevertChangesButton         matlab.ui.control.Button
        gridScaleLabel                  matlab.ui.control.Label
        gridTranslationLabel            matlab.ui.control.Label
        gridtransY                      matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        gridtransX                      matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        ShowZvalueforcellEditField      matlab.ui.control.NumericEditField
        ShowZvalueforcellEditFieldLabel  matlab.ui.control.Label
        ZoutputEditField                matlab.ui.control.NumericEditField
        GridControlLabel                matlab.ui.control.Label
        TransparencySlider              matlab.ui.control.Slider
        GridTransparencySliderLabel     matlab.ui.control.Label
        gridscaleY                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        gridTransformButton             matlab.ui.control.Button
        gridScaleX                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        ShowGridCellNumbersCheckBox     matlab.ui.control.CheckBox
        SetupLabel                      matlab.ui.control.Label
        UpdaterLabel                    matlab.ui.control.Label
        updaterCellStateValueEditField  matlab.ui.control.NumericEditField
        CellStateValueEditFieldLabel    matlab.ui.control.Label
        UpdaterButton                   matlab.ui.control.Button
        updaterGridCellNumberEditField  matlab.ui.control.NumericEditField
        GridCellNumberEditFieldLabel    matlab.ui.control.Label
        setupTable                      matlab.ui.control.Table
        setUpAddRowButton               matlab.ui.control.Button
        setUpDeleteRowButton            matlab.ui.control.Button
        RowtoDeleteEditFieldLabel       matlab.ui.control.Label
        setUpRowtoDeleteEditField       matlab.ui.control.NumericEditField
        UpdateImageButton               matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        ImageEditField                  matlab.ui.control.NumericEditField
        ImageEditFieldLabel             matlab.ui.control.Label
        NextImageButton                 matlab.ui.control.Button
        CountResultsLabel               matlab.ui.control.Label
        ResultTable                     matlab.ui.control.Table
        HistAxes                        matlab.ui.control.UIAxes
        ImageAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    %input vars
        im      % the current image
        im_all  % the full image
        im_range % [first image to count, last image to count]
        im_ind  % index of current image
        pgs     % grid polygons can be transfromed within the app
        pgs_o   % original grid polygons
        outs    % number of any grid cell that should not be included in calcs
    %graph vars
        H_IM    % histogram object
        H_P     % plotted polygon object
        cm1
        zMethod % how to calculate z value for histogram
    %calculated vars
        %calc at start up
            numPs   % number of grid cells
        % output of calcZAvg function
            zs      % stores z value for each grid cell. 
            midps   % [x, y] coord for center of each grid cell
        % output of determineState function
            state   % stores state value (0 = unbound, 1 = bound, 2 = other) for every cell
            c_num   % state of each grid cell - used to determine which color the cell is plotted
            t_color % colors to for grid polygons
        % final output
            count_out
            state_out
            zAvg_out
    end
    
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%start of calcZAvg function%%%%%%%%%%%%%%%%%
        function calcZAvg(app)
            Xlen = size(app.im,2);
            Ylen = size(app.im,1);
            image_1=app.im;
            plygon = app.pgs;
            %get coordinates of all pixels within image
            count = 1;
            coord = zeros(Xlen*Ylen, 2);
            for i = 1:Xlen
                for ii = 1: Ylen
                    coord(count, :) = [i, ii];
                    count=count+1;
                end
            end
            % get points within polygon and average z values
            vertp = zeros(4, 2, app.numPs);
            pzAvg = zeros(1, app.numPs);
            pzMax = zeros(1, app.numPs);
            vertp(:,:,1) = plygon(1).Vertices;
            %calculate cell area
            a_pix = sqrt(sum(cross([vertp(1,:,1)-vertp(2,:,1),0], [vertp(1,:,1)-vertp(4,:,1),0]).^2));
            
            % determine which image coordinates are within grid cell p
            % (inp2) then calcuate the average value
            for p = 1:app.numPs
                vertp(:,:,p) = plygon(p).Vertices;
                inp1 = inpolygon(coord(:,1), coord(:,2), vertp(:, 1, p), vertp(:, 2, p));
                inp2 = coord.*inp1;
                inp2(inp2==0) =[];
                inp2 = reshape(inp2, [], 2);
                
                zs1= zeros(size(inp2,1),1);
                for k = 1:size(inp2,1)
                    x_c =  inp2(k, 1);
                    y_c = inp2(k,2);
                    zs1(k) = image_1(y_c, x_c);
                end
                c_nan = sum(isnan(zs1));
                %if less than half of pixels are nan (i.e. more than half of grid
                %cell is in the image area) then calculate the mean and max inside
                %grid cell
                if c_nan/size(zs1,1) < 0.5 && size(zs1,1)/a_pix > 0.5%number of nan pixels/ total number of pixels 
                    pzAvg(p) = mean(zs1, 'omitnan');
                    pzMax(p) = max(zs1);
                    zVarSq(p) = mean(((zs1-pzAvg(p)).^2), 'omitnan');
                else
                    pzAvg(p) = NaN;
                    pzMax(p) = NaN;
                    zVarSq(p) = NaN;
                end
            end
            % calculate grid cell center point
            midps2 = [(vertp(1, 1, :) + vertp(3, 1, :))/2,  (vertp(1, 2, :) + vertp(3, 2, :))/2];
            %reshape midps
            app.midps = zeros(app.numPs, 2);
            for p = 1:app.numPs
                app.midps(p, :) = midps2(:,:,p);
            end
            
            pzAvg(app.outs) = NaN;
            pzMax(app.outs) = NaN;
            zVarSq(app.outs) = NaN;
            switch app.zMethod
                case 'Average'
                    app.zs = pzAvg;
                case 'Maximum'
                    app.zs = pzMax;
                case 'Variance Sq'
                    app.zs = zVarSq;
            end
            plotHist(app)
            
        end %%end calcZAvg function
        
        %%%%%%%%%%%%%%%%%%%start of plotHist function%%%%%%%%%%%%%%%%%
        function plotHist(app)
            app.H_IM = histogram(app.HistAxes,app.zs);
        end %% end plotHist
        
        %%%%%%%%%%%%%%%%%%%start of plotIm function%%%%%%%%%%%%%%%%%%%
        function plotIm(app)
            imagesc(app.ImageAxes, app.im); pbaspect(app.ImageAxes,[1, size(app.im,1)/size(app.im,2), 1]); colormap(app.ImageAxes,app.cm1);
            hold(app.ImageAxes, 'on')
            title(app.ImageAxes, 'Image 1')
            app.H_P = plot(app.ImageAxes, app.pgs, 'FaceAlpha',app.TransparencySlider.Value);
            hold(app.ImageAxes, 'off')
        end %% end plotIM
        
        %%%%%%%%%%%%%%%%%%%start of plotPG function%%%%%%%%%%%%%%%%%%%
        function plotPG(app)
            t_color1 = [app.setupTable.Data.('R'),app.setupTable.Data.('G'),app.setupTable.Data.('B')];
            app.t_color = [t_color1; 1 0 0];
            for p = 1:app.numPs
                app.H_P(p).FaceColor = app.t_color(app.c_num(p),:);
            end
            if app.ShowGridCellNumbersCheckBox.Value == 1
                plotGridNums(app)
            end
        end %% end plotPG
        
        %%%%%%%%%%%%%%%%%%%start of plotGridNums function%%%%%%%%%%%%%
        function plotGridNums(app)
            hold(app.ImageAxes, 'on')
            for p = 1:app.numPs
                text(app.ImageAxes, app.midps(p, 1)-3, app.midps(p, 2), num2str(p), 'Color', 'c', 'FontSize', 11, 'FontWeight','bold');
            end
            hold(app.ImageAxes, 'off')
        end %% end plotGridNums
        
        %%%%%%%%%%%%%%%%%%%start of determineState function%%%%%%%%%%%
        function determineState(app)
            t1 = app.setupTable.Data;
            num_levels = size(t1,1);
            state_val = t1.('State Value');
            thld = zeros(num_levels,2);
            thld(:,2) = t1.('Upper Threshold');
            thld(:,1) = [min(app.zs);thld(1:end-1,2)];
            part_count = zeros(num_levels,1);
            app.state = zeros(num_levels,1);
            t_name = t1.('State Name');
            
            for p = 1:app.numPs
                for n = 1:num_levels
                    if n ~= num_levels
                        if app.zs(p) >= thld(n, 1) && app.zs(p) < thld(n, 2)
                            app.c_num(p) = n;
                            part_count(n) = part_count(n)+1;
                            app.state(p) = state_val(n);
                        elseif isnan(app.zs(p))
                            app.c_num(p) = num_levels+1;
                            app.state(p) = 2;
                        end
                    else
                        if app.zs(p) >= thld(n, 1) && app.zs(p) <= thld(n, 2)
                            app.c_num(p) = n;
                            part_count(n) = part_count(n)+1;
                            app.state(p) = state_val(n);
                        elseif isnan(app.zs(p))
                            app.c_num(p) = num_levels+1;
                            app.state(p) = 2;
                        end
                    end
                end % end n for loop
            end % end p for loop
            
            tot_mol = sum(part_count);            
            thresholdResult = table([{'total'}; t_name], [tot_mol; part_count], ([tot_mol; part_count]/tot_mol)*100, 'VariableNames', {'State Name', 'Count', 'Percent'});
            app.ResultTable.Data = thresholdResult;
        end %% end determineState
            
    end % end methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, pgs, out_cell, im_range)
        %set up window
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.ImageAxes.Visible = 'off';
            axis(app.ImageAxes, 'image');
        %initialize variables
            app.im_range = im_range;
            app.outs = out_cell;
            app.pgs_o = pgs;
            app.pgs = pgs;
            app.state = zeros(length(pgs),1);
            app.numPs = length(pgs);
            app.c_num = app.state;
            app.ShowZvalueforcellEditField.Limits = [0, app.numPs];
            app.updaterGridCellNumberEditField.Limits = [0, app.numPs];
            app.zMethod = app.DropDown.Value;
            %initialize input table
                startN = [{'Bound'};{'Unbound'}];
                startState = [0;1];
                startThres = [0;1];
                startColor = [0; 1];
                tabled = table(startN,startThres,startState,startColor,startColor,startColor,'VariableNames', {'State Name','Upper Threshold','State Value','R','G','B'});
                app.setupTable.Data = tabled;
                app.updaterTable.Data = table('Size', [0,3], 'VariableTypes', {'double','double','double'}, 'VariableNames', {'-','Number', 'Value'});
            %initialize image
            numim = im_range(2) - im_range(1) + 1;
            app.im_ind = im_range(1);
            app.im_all = im;
            app.im = im(:,:,app.im_ind);
            app.count_out = cell(numim, 3);
            app.zAvg_out = zeros(numim, app.numPs);
            app.state_out = zeros(numim, app.numPs);
        app.cm1 = load('SPIPColormap.mat');
        app.cm1 = app.cm1.SPIPColormap;
        %initial plot and calculations
            plotIm(app)
            calcZAvg(app)
            app.ImageEditField.Value = app.im_ind;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {695, 695};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {488, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.zMethod = app.DropDown.Value;
            calcZAvg(app)
            determineState(app)
            plotPG(app)
        end

        % Button pushed function: UpdateImageButton
        function UpdateImageButtonPushed(app, event)
            determineState(app)
            plotPG(app)
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            %save results
            if ~isempty(app.ResultTable.Data)
                app.count_out( (app.im_ind-app.im_range(1)+1), :) = num2cell(app.ResultTable.Data.('Count')');
                app.state_out( (app.im_ind-app.im_range(1)+1), :) = app.state';
                app.zAvg_out( (app.im_ind-app.im_range(1)+1), :) = app.zs;
            end
            
            % update image 
            app.im_ind = app.im_ind + 1;
            app.ImageEditField.Value = app.im_ind;
            if app.im_ind <= app.im_range(2)
                app.im = app.im_all(:,:,app.im_ind);
                calcZAvg(app)
                determineState(app)
                plotIm(app)
                plotPG(app)
                
                %update show z
                value = app.ShowZvalueforcellEditField.Value;
                z_show = app.zs(round(value));
                if isnan(z_show)
                    z_show = -10;
                end
                app.ZoutputEditField.Value = z_show;
            else
                UIFigureCloseRequest(app)
            end
        end

        % Button pushed function: setUpAddRowButton
        function setUpAddRowButtonPushed(app, event)
           newrow = table({'new state'}, 1, 2, 0, 0, 0,'VariableNames', {'State Name','Upper Threshold','State Value','R','G','B'});
           app.setupTable.Data = [app.setupTable.Data; newrow];
        end

        % Button pushed function: setUpDeleteRowButton
        function setUpDeleteRowButtonPushed(app, event)
            row_in = app.setUpRowtoDeleteEditField.Value;
            if row_in == 0 || row_in > size(app.setupTable,1)
                row_in = size(app.setupTable,1);
            end
            app.setupTable.Data(row_in, :) = [];
        end

        % Button pushed function: updaterClearTableButton
        function updaterClearTableButtonPushed(app, event)
            app.updaterTable.Data(size(app.updaterTable.Data,1),:) = [];
        end

        % Button pushed function: updaterAddtoTableButton
        function updaterAddtoTableButtonPushed(app, event)
            newrow = table(size(app.updaterTable.Data,1)+1, app.updaterGridCellNumberEditField.Value, app.updaterCellStateValueEditField.Value, 'VariableNames', {'-','Number', 'Value'});
            app.updaterTable.Data = [app.updaterTable.Data; newrow];
        end

        % Button pushed function: updaterDeleteRowButton
        function updaterDeleteRowButtonPushed(app, event)
            row_in = app.updaterRowtoDeleteEditField.Value;
            app.updaterTable.Data(row_in, :) = [];
            app.updaterTable.Data.('-') = [1:size(app.updaterTable.Data,1)]';
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            svalue = app.updaterTable.Data.('Value'); % new state value
            pnum = app.updaterTable.Data.('Number');   % cell num to use
            states = app.setupTable.Data.('State Value');
                        
            % update the color value and plot
            for i = 1:length(svalue)
                s_old = app.state(pnum(i));                % current state value for cell pnum
                app.state(pnum(i)) = svalue(i);                   % update cell value to new
                
                f1 = find(states==svalue(i));
                if isempty(f1)
                    f1 = length(states)+1;
                else
                    app.ResultTable.Data.('Count')(f1+1)=app.ResultTable.Data.('Count')(f1+1)+1;        % add 1 to new count
                end
                app.c_num(pnum(i)) = f1;
                app.H_P(pnum(i)).FaceColor = app.t_color(f1,:);
                
                % update result table
                f_old = find(states==s_old);
                if ~isempty(f_old)
                    app.ResultTable.Data.('Count')(f_old+1)=app.ResultTable.Data.('Count')(f_old+1)-1; % subtract 1 from original count
                end
            end
            
            app.ResultTable.Data.('Count')(1) = sum(app.ResultTable.Data.('Count')(2:end)); % recalculate total
            app.ResultTable.Data.('Percent')(2:end)=app.ResultTable.Data.('Count')(2:end)/sum(app.ResultTable.Data.('Count')(2:end))*100; % recalculate percentages
        end

        % Value changed function: ShowZvalueforcellEditField
        function ShowZvalueforcellEditFieldValueChanged(app, event)
            value = app.ShowZvalueforcellEditField.Value;
            z_show = app.zs(round(value));
            if isnan(z_show)
                z_show = -10;
            end
            app.ZoutputEditField.Value = z_show;
        end

        % Value changed function: ShowGridCellNumbersCheckBox
        function ShowGridCellNumbersCheckBoxValueChanged(app, event)
            if app.ShowGridCellNumbersCheckBox.Value == 0
                plotIm(app)
            end
            plotPG(app)
        end

        % Button pushed function: gridTransformButton
        function gridTransformButtonPushed(app, event)
            x_t = round(app.gridtransX.Value);
            y_t = round(app.gridtransY.Value);
            app.pgs = translate(app.pgs, x_t, y_t);
            app.pgs = scale(app.pgs, [app.gridScaleX.Value, app.gridscaleY.Value], [1,1]);
            app.pgs = rotate(app.pgs, (app.gridRotation.Value), [1,1]);
            calcZAvg(app)
            determineState(app)
            plotIm(app)
            plotPG(app)
        end

        % Button pushed function: gridRevertChangesButton
        function gridRevertChangesButtonPushed(app, event)
            app.pgs = app.pgs_o;
            calcZAvg(app)
            determineState(app)
            plotIm(app)
            plotPG(app)
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            value = app.TransparencySlider.Value;
            for p = 1:app.numPs
                app.H_P(p).FaceAlpha = value;
            end
            app.transparencyValue.Value = value;
        end

        % Value changed function: transparencyValue
        function transparencyValueValueChanged(app, event)
            value = app.transparencyValue.Value;
            for p = 1:app.numPs
                app.H_P(p).FaceAlpha = value;
            end
            app.TransparencySlider.Value = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.ResultTable.Data)
                if app.im_ind <= app.im_range(2)
                    app.count_out( (app.im_ind-app.im_range(1)+1), :) = num2cell(app.ResultTable.Data.('Count')');
                    app.state_out( (app.im_ind-app.im_range(1)+1), :) = app.state';
                    app.zAvg_out( (app.im_ind-app.im_range(1)+1), :) = app.zs;
                end
                
                assignin('base', 'count_out', cell2mat(app.count_out));
                assignin('base', 'state_out', app.state_out);
                assignin('base', 'zAvg_out', app.zAvg_out);
                assignin('base', 'end_ind', app.im_ind);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UpdateImageButton = uibutton(app.LeftPanel, 'push');
            app.UpdateImageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageButtonPushed, true);
            app.UpdateImageButton.Text = 'Update Image';
            app.setUpRowtoDeleteEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RowtoDeleteEditFieldLabel = uilabel(app.LeftPanel);
            app.RowtoDeleteEditFieldLabel.Text = 'Row to Delete';
            app.setUpDeleteRowButton = uibutton(app.LeftPanel, 'push');
            app.setUpDeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @setUpDeleteRowButtonPushed, true);
            app.setUpDeleteRowButton.Text = 'Delete Row';
            app.setUpAddRowButton = uibutton(app.LeftPanel, 'push');
            app.setUpAddRowButton.ButtonPushedFcn = createCallbackFcn(app, @setUpAddRowButtonPushed, true);
            app.setUpAddRowButton.Text = 'Add Row';
            app.setupTable = uitable(app.LeftPanel);
            app.setupTable.ColumnName = {'State Name'; 'Upper Threshold'; 'State Value'; 'Color'};
            app.setupTable.RowName = {};
            app.setupTable.ColumnSortable = true;
            app.setupTable.ColumnEditable = true;
            app.GridCellNumberEditFieldLabel = uilabel(app.LeftPanel);
            app.GridCellNumberEditFieldLabel.Text = 'Grid Cell Number';
            app.updaterGridCellNumberEditField = uieditfield(app.LeftPanel, 'numeric');
            app.UpdaterButton = uibutton(app.LeftPanel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update Cell Values';
            app.CellStateValueEditFieldLabel = uilabel(app.LeftPanel);
            app.CellStateValueEditFieldLabel.Text = 'Cell State Value';
            app.updaterCellStateValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.UpdaterLabel = uilabel(app.LeftPanel);
            app.UpdaterLabel.Text = 'Manual Cell Updater';
            app.SetupLabel = uilabel(app.LeftPanel);
            app.SetupLabel.Text = 'Set-up';
            app.ShowGridCellNumbersCheckBox = uicheckbox(app.LeftPanel);
            app.ShowGridCellNumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGridCellNumbersCheckBoxValueChanged, true);
            app.ShowGridCellNumbersCheckBox.Text = 'Show Grid Cell Numbers';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.gridScaleX = uieditfield(app.LeftPanel, 'numeric');
            app.gridScaleX.Limits = [0 Inf];
            app.gridScaleX.Value = 1;
            app.gridTransformButton = uibutton(app.LeftPanel, 'push');
            app.gridTransformButton.ButtonPushedFcn = createCallbackFcn(app, @gridTransformButtonPushed, true);
            app.gridTransformButton.Text = 'Transform Grid';
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.gridscaleY = uieditfield(app.LeftPanel, 'numeric');
            app.gridscaleY.Limits = [0 Inf];
            app.gridscaleY.Value = 1;
            app.GridTransparencySliderLabel = uilabel(app.LeftPanel);
            app.GridTransparencySliderLabel.Text = 'Grid Transparency';
            app.TransparencySlider = uislider(app.LeftPanel);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.TransparencySlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.MinorTicks = [0 0.1 0.3 0.5 0.7 0.9];
            app.TransparencySlider.Value = 0.5;
            app.GridControlLabel = uilabel(app.LeftPanel);
            app.GridControlLabel.Text = 'Grid Control';
            app.ZoutputEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ZoutputEditField.Editable = 'off';
            app.ShowZvalueforcellEditFieldLabel = uilabel(app.LeftPanel);
            app.ShowZvalueforcellEditFieldLabel.Text = 'Show Z value for cell #:';
            app.ShowZvalueforcellEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShowZvalueforcellEditField.Limits = [0 Inf];
            app.ShowZvalueforcellEditField.RoundFractionalValues = 'on';
            app.ShowZvalueforcellEditField.ValueChangedFcn = createCallbackFcn(app, @ShowZvalueforcellEditFieldValueChanged, true);
            app.ShowZvalueforcellEditField.Value = 1;
            app.ZLabel = uilabel(app.LeftPanel);
            app.ZLabel.Text = 'Z=';
            app.XEditField_2Label = uilabel(app.LeftPanel);
            app.XEditField_2Label.Text = 'X';
            app.gridtransX = uieditfield(app.LeftPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.LeftPanel);
            app.YEditField_2Label.Text = 'Y';
            app.gridtransY = uieditfield(app.LeftPanel, 'numeric');
            app.gridTranslationLabel = uilabel(app.LeftPanel);
            app.gridTranslationLabel.Text = 'Translation:';
            app.gridScaleLabel = uilabel(app.LeftPanel);
            app.gridScaleLabel.Text = 'Scale:';
            app.gridRevertChangesButton = uibutton(app.LeftPanel, 'push');
            app.gridRevertChangesButton.ButtonPushedFcn = createCallbackFcn(app, @gridRevertChangesButtonPushed, true);
            app.gridRevertChangesButton.Text = 'Revert Changes';
            app.transparencyValue = uieditfield(app.LeftPanel, 'numeric');
            app.transparencyValue.Limits = [0 1];
            app.transparencyValue.ValueChangedFcn = createCallbackFcn(app, @transparencyValueValueChanged, true);
            app.transparencyValue.Value = 0.5;
            app.updaterTable = uitable(app.LeftPanel);
            app.updaterTable.ColumnName = {'Row'; 'Number'; 'Value'};
            app.updaterTable.RowName = {};
            app.updaterTable.ColumnEditable = true;
            app.updaterAddtoTableButton = uibutton(app.LeftPanel, 'push');
            app.updaterAddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @updaterAddtoTableButtonPushed, true);
            app.updaterAddtoTableButton.Text = 'Add to table';
            app.updaterDeleteRowButton = uibutton(app.LeftPanel, 'push');
            app.updaterDeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @updaterDeleteRowButtonPushed, true);
            app.updaterDeleteRowButton.Text = 'Delete Row';
            app.updaterRowtoDeleteEditField = uieditfield(app.LeftPanel, 'numeric');
            app.updaterRowtoDeleteEditField.Limits = [1 Inf];
            app.updaterRowtoDeleteEditField.Value = 1;
            app.RowtoDeleteEditField_2Label = uilabel(app.LeftPanel);
            app.RowtoDeleteEditField_2Label.Text = 'Row to Delete';
            app.updaterClearTableButton = uibutton(app.LeftPanel, 'push');
            app.updaterClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @updaterClearTableButtonPushed, true);
            app.updaterClearTableButton.Text = 'Clear Table';
            app.RotationdegreesLabel = uilabel(app.LeftPanel);
            app.RotationdegreesLabel.Text = 'Rotation (degrees):';
            app.gridRotation = uieditfield(app.LeftPanel, 'numeric');
            app.gridRotation.Limits = [-360 360];
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.RightPanel);
            zlabel(app.ImageAxes, 'Z')
            app.HistAxes = uiaxes(app.RightPanel);
            xlabel(app.HistAxes, 'Apparent Height')
            ylabel(app.HistAxes, 'Count')
            zlabel(app.HistAxes, 'Z')
            app.ResultTable = uitable(app.RightPanel);
            app.ResultTable.ColumnName = {'State Name'; 'Count'; 'Percent'};
            app.ResultTable.RowName = {};
            app.CountResultsLabel = uilabel(app.RightPanel);
            app.CountResultsLabel.Text = 'Count Results';
            app.NextImageButton = uibutton(app.RightPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.ImageEditFieldLabel = uilabel(app.RightPanel);
            app.ImageEditFieldLabel.Text = 'Image:';
            app.ImageEditField = uieditfield(app.RightPanel, 'numeric');
            app.ImageEditField.Editable = 'off';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Items = {'Average', 'Maximum', 'Variance Sq'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Variance Sq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageThresholdCounter1_4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end