classdef unitCellOverlay1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FitGridButton                  matlab.ui.control.Button
        a_left                         matlab.ui.control.NumericEditField
        leftEditFieldLabel_2           matlab.ui.control.Label
        anonlinearityLabel             matlab.ui.control.Label
        a_right                        matlab.ui.control.NumericEditField
        rightEditFieldLabel_2          matlab.ui.control.Label
        bnonlinearityLabel             matlab.ui.control.Label
        b_left                         matlab.ui.control.NumericEditField
        leftEditFieldLabel             matlab.ui.control.Label
        ShowvectorsoffsetCheckBox      matlab.ui.control.CheckBox
        UpdatePlotButton               matlab.ui.control.Button
        ShowbvectorsredCheckBox        matlab.ui.control.CheckBox
        ShowavectorsblueCheckBox       matlab.ui.control.CheckBox
        GetNewStartingVectorsButton    matlab.ui.control.Button
        b_right                        matlab.ui.control.NumericEditField
        rightEditFieldLabel            matlab.ui.control.Label
        bSepEditField                  matlab.ui.control.NumericEditField
        bbseperationpixEditFieldLabel  matlab.ui.control.Label
        aSepEditField                  matlab.ui.control.NumericEditField
        aaseperationpixEditFieldLabel  matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        FinishandCloseButton           matlab.ui.control.Button
        ImageAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
    end
    
    properties (Access = private)
        SzX;
        SzY;
        a;%not used currently
        b;%not used currently
        yint_a;
        yint_b;
        im2;
        line_a;
        line_b;
        origin;
        fileIn;
        sep_a = 10;
        sep_b = 10;
        offset = 0;
        plot_a = 1;
        plot_b = 1;
        nlin_a_l = 0;
        nlin_a_r = 0;
        nlin_b_l = 0;
        nlin_b_r = 0;
        ints;
        intshifted;
        outGraph;
    end
    
    methods (Access = private)
        
        function loadImageFunc(app, varargin)
            switch nargin
                case 1
                    [fn, fp] = uigetfile({'*.tif';'*.jpg';'*.png';'.'}, 'Select Image File');
                    if (ischar(fp)) %check to make sure user didn't cancel
                        app.fileIn = [fp fn];
                        im = imread(app.fileIn);
                    end
                case 3
                    if ischar(varargin{2})
                        im = imread(varargin{2});
                    else
                        im = (varargin{2});
                    end
                otherwise
                    disp('Error Loading Image. Choosing new image now')
                    [fn, fp] = uigetfile({'*.tif';'*.jpg';'*.png';'.'}, 'Select Image File');
                    if (ischar(fp)) %check to make sure user didn't cancel
                        app.fileIn = [fp fn];
                        im = imread(app.fileIn);
                    end
            end

               [app.SzY, app.SzX, Sz3] = size(im);
               if Sz3 >= 4
                  app.im2 = im(:,:,1:3);
               else
                   app.im2 = im;
               end
               app.ImageAxes.XLim = [0 app.SzX];
               app.ImageAxes.YLim = [0 app.SzY];
               imagesc(app.ImageAxes, app.im2);
        end
        
        function getVectors(app)
            %get number of vectors needed to cover image
            xa_0 = -app.line_a(2)/app.line_a(1);
            xa_T = (app.SzY - app.line_a(2))/app.line_a(1);
            lenTB_a = sqrt((app.SzY - 0)^2 + (xa_T - xa_0)^2);
            num_a = round(lenTB_a/app.sep_a)+30;
            
            %numb of bs
            xb_0 = -app.line_b(2)/app.line_b(1);
            xb_T = (app.SzY - app.line_b(2))/app.line_b(1);
            lenTB_b = sqrt((app.SzY - 0)^2 + (xb_T - xb_0)^2);
            num_b = round(lenTB_b/app.sep_b)+30;
            
            %get vector y-intercepts
            yints_a = zeros(1, num_a*2);
            yints_b = zeros(1, num_b*2);
            fudge_a_l = 1 + (app.nlin_a_l)/100;
            fudge_b_l = 1 + (app.nlin_b_l)/100;
            fudge_a_r = 1 + (app.nlin_a_r)/100;
            fudge_b_r = 1 + (app.nlin_b_r)/100;
            for ha = 1 : (num_a*2)
                if ha <= num_a  % ha = 1 through num_a
                    yints_a(ha) = app.line_a(2) - (ha-1)*(app.sep_a * fudge_a_r);
                else            %ha > num_a
                    yints_a(ha) = app.line_a(2) + (ha-num_a)*(app.sep_a * fudge_a_l);
                end
            end
            for hb = 1 : (num_b*2)
                if hb <= num_b
                    yints_b(hb) = app.line_b(2) - (hb-1)*(app.sep_b * fudge_b_l);
                else
                    yints_b(hb) = app.line_b(2) + (hb-num_b)*(app.sep_b * fudge_b_r);
                end
            end
            
            %save yints for later use
            app.yint_a = yints_a;
            app.yint_b = yints_b;
            
        end
        
        function getSlopes(app)
            fontSz = 11;
            
            figure;
            imagesc(app.im2)
            truesize; colormap('gray');
            axis([app.SzX/3 (app.SzX/3*2-1) app.SzY/3 (app.SzY/3*2-1)]); %zoom to middle third of image
            title('pick points along unit cell vector a', 'FontSize', fontSz)
            subtitle('press enter when finished','FontSize', fontSz-2)
            [a_x, a_y] = getpts;
            title('pick points along unit cell vector b', 'FontSize', fontSz)
            [b_x, b_y] = getpts;
%             title('pick origin', 'FontSize', fontSz)
%             [origin_x, origin_y] = getpts;
            origin_x = 0;
            origin_y = 0;
            close;
            
            fline_a = fit(a_x, a_y, 'poly1');
            fline_b = fit(b_x, b_y, 'poly1');
                        
            app.line_a = [fline_a.p1, fline_a.p2];
            app.line_b = [fline_b.p1, fline_b.p2];
            app.origin = [origin_x, origin_y];
        end
        
        function plotVectors(app)
            imagesc(app.ImageAxes, app.im2);
            hold(app.ImageAxes, 'on')
            x = 1:app.SzX;
            if app.plot_b == 1
                for i = 1: length(app.yint_b)
                    line = app.line_b(1)*x + app.yint_b(i) + app.offset*app.sep_b/2;
                    if i == 1
                        plot(app.ImageAxes, x, line, 'Color', 'k')
                    else
                        plot(app.ImageAxes, x, line, 'Color', 'r')
                    end
                    
                end
            end
            if app.plot_a == 1
                for i = 1: length(app.yint_a) 
                    line = app.line_a(1)*x + app.yint_a(i) + app.offset*app.sep_a/2;
                    if i == 1
                        plot(app.ImageAxes, x, line, 'Color', 'k')
                    else
                        plot(app.ImageAxes, x, line, 'Color', 'c')
                    end
                    
                end
            end
            hold(app.ImageAxes, 'off')
        end
        
        function findIntersects(app)
            % sort all y-intercepts
            app.yint_a=sortrows(app.yint_a')';
            app.yint_b=sortrows(app.yint_b')';
            %create variables to hold line a & b intersects
            app.ints = zeros((length(app.yint_a)*length(app.yint_b)), 2);
            app.intshifted = zeros((length(app.yint_a)*length(app.yint_b)), 2);
            count = 1;
            %find all a & b line intersections
            for hh = 1:length(app.yint_a)
                for vv = 1:length(app.yint_b)
                  app.ints(count, :) = [1 ,-app.line_a(1); 1, -app.line_b(1)] \ [app.yint_a(1,hh); app.yint_b(1,vv)];
                  app.intshifted(count, :) = [1 ,-app.line_a(1); 1, -app.line_b(1)] \ [app.yint_a(1,hh)-app.sep_a/2; app.yint_b(1,vv)+app.sep_b/2];
                  count = count+1;
                end
            end
            
            %get rid of intersections outside of image window
            for lx = size(app.intshifted,1):-1:1
                if app.intshifted(lx, 2) <= -10 || app.intshifted(lx,1)<= -10 || app.intshifted(lx,1) >= app.SzY+10 || app.intshifted(lx, 2) >= app.SzX+10
                    app.intshifted(lx,:) = [];
                    app.ints(lx,:) = [];
                end
            end
        end %end findIntercepts
        
        function fitGraph(app)
        % fitGraph takes in xy coordinates of graph nodes. Usually coordinates of
        % molecule centers and outputs a connected graph variable that show how all
        % nodes are connected with their neighbors
        % input: nodes = nx2 array that has all coordinates for n nodes, [x,y]
        %        maxConnections = a number with the maximum number of nearest
        %        neigbors for a node. 4 = rectangular grid
        %        varargin = optional input of distance to search for neighboring
        %        nodes either 1 or 2 values. Use 2 different values if the image is
        %        stretched and top half distance is different from bottom half
        % output: 
        %   outGraph = graph object that can be plotted in matlab. Lists all
        %       connections between nodes eg. if node 1 is connected to node 2 & 5. row 1
        %       in outGraph would = [1 2 5]
        %   minSearch = optional output that outputs the search distance
        %       used in determining outGraph
            nodes = app.ints;
            num_nodes = size(nodes, 1);
            rnd = round(num_nodes/4);
            maxConnections = 4;
            
            % determine/set search distance 
            dist1 = sqrt( ((nodes(rnd,1) - nodes(:,1)).^2) + ((nodes(rnd,2) - nodes(:,2)).^2) ); %calculate distance between node rnd and all other nodes
            B = mink(dist1, maxConnections*4); % find the (maxConnections * 4) # of smallest distances from dist1. minimum distances will be the distances for nearest neighbors.
            minSearch1 = uniquetol(B); % keep only unique distances from B
            minSearch = (minSearch1(maxConnections/2+1)+minSearch1(maxConnections/2+2))/2; %assume half of the maxConnections are unique lengths and calculate average length that is just longer than that
            
            dist2 = sqrt( ((nodes(rnd*3,1) - nodes(:,1)).^2) + ((nodes(rnd*3,2) - nodes(:,2)).^2) ); % repeat above with different initial node, useful if image is streched around the edges otherwise will be same as minsearch(1)
            B2 = mink(dist2, maxConnections*4);
            minSearch2 = uniquetol(B2);
            minSearch_2 = (minSearch2(maxConnections/2+1)+minSearch2(maxConnections/2+2))/2;
     
             
            % make adjacency matrix
            ad_in = zeros(num_nodes);
            for n = 1:num_nodes
                que = nodes(n,:);
                if n <= round(num_nodes*0.5)              
                    idx = rangesearch(que, nodes, minSearch);
                else
                    idx = rangesearch(que, nodes, minSearch_2);
                end 
                tf = cellfun('isempty', idx);
                idx(tf) = {0};
                ad_in(:,n)= cell2mat(idx);
            end    
            ad_in = ad_in - diag(ones(1,num_nodes));
            app.outGraph = graph(ad_in~=0,'upper', 'omitselfloops');   
        end %%%%%%%%%%%%%end fitGraph function
        
%         function guessSep(app) % not finished
%             sep = [(app.a*cos((pi*angle01)/180)), (app.b*cos((pi*(180-angle02))/180)); app.a*sin((pi*angle01)/180)), (app.b*sin((pi*(180-angle02))/180));];
%             p2 = [origin(1) - sep(1,1), origin(2) + sep(2,1)];
%             p3 = [p2(1) + sep(1,2), p2(2) - sep(2,2)];
%             p4 = [origin(1) + sep(1,2), origin(2) - sep(2,2)];
%             ps = [origin; p2; p3; p4;];
%             y34 = p3(2) - line3.p1*p3(1);
%             y23 = p2(2) - line4.p1*p2(1);
%             y04 = p4(2) - line4.p1*p4(1);
%             y02 = line3.p2;
%             ysep_a = abs(y34 - yint(1));
%             ysep_b = abs(y23 - yint(2));
%         end
    end %end methods funtions - private 


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            switch nargin
                case 1    
                    app.loadImageFunc(app);
                case 2
                    app.loadImageFunc(app, varargin{1});
                case 4
                    app.a = varargin{2};
                    app.b = varargin{3};
                    %app.guessSep;
                    app.loadImageFunc(app, varargin{1});
                otherwise
                    warning('incorrect input arguments, load new image')
                    app.loadImageFunc(app);
            end
                                  
            getSlopes(app);
            getVectors(app);
            plotVectors(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {546, 546};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: UpdatePlotButton, aSepEditField, a_left, 
        % a_right, bSepEditField, b_left, b_right
        function recalculateVectors(app, event)
            app.sep_a = app.aSepEditField.Value;
            app.sep_b = app.bSepEditField.Value;
            app.nlin_a_l = app.a_left.Value;
            app.nlin_a_r = app.a_right.Value;
            app.nlin_b_r = app.b_right.Value;
            app.nlin_b_l = app.b_left.Value;
            app.plot_a = app.ShowavectorsblueCheckBox.Value;
            app.plot_b = app.ShowbvectorsredCheckBox.Value;
            app.offset = app.ShowvectorsoffsetCheckBox.Value;
                        
            getVectors(app);
            plotVectors(app);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            app.loadImageFunc;
        end

        % Button pushed function: GetNewStartingVectorsButton
        function GetNewStartingVectorsButtonPushed(app, event)
            getSlopes(app);
            getVectors(app);
            plotVectors(app);
        end

        % Callback function: FinishandCloseButton, UIFigure
        function closeDownFcn(app, event)
            if ~isempty(app.yint_a)
                                
                %save vector info
                vectInfo = table('Size', [2, 5],'VariableTypes', {'double','double','double','double','double'}, 'VariableNames', {'slope', 'y-int', 'seperation (pix)', 'nonlinearity left (%)', 'nonlinearity right (%)'}, 'RowNames', {'a', 'b'});
                vectInfo('a', :) = {app.line_a(1), app.line_a(2), app.sep_a, app.nlin_a_l, app.nlin_a_r};
                vectInfo('b', :) = {app.line_b(1), app.line_b(2), app.sep_b, app.nlin_b_l, app.nlin_b_r};
                
                %find all a & b intersection points
                findIntersects(app)
                
                %send ints and vectInfo to workspace
                assignin('base', 'ints', app.ints);
                assignin('base', 'intshifted', app.intshifted);
                assignin('base', 'unitCellGridInfo', vectInfo);
                disp('Unit cell grid complete. Info saved to workspace.')
            end
            
            %close and delete app
            delete(app)
        end

        % Value changed function: ShowavectorsblueCheckBox, 
        % ShowbvectorsredCheckBox, ShowvectorsoffsetCheckBox
        function replotVectors(app, event)
            app.plot_a = app.ShowavectorsblueCheckBox.Value;
            app.plot_b = app.ShowbvectorsredCheckBox.Value;
            app.offset = app.ShowvectorsoffsetCheckBox.Value;
            
            plotVectors(app);
        end

        % Button pushed function: FitGridButton
        function FitGridButtonPushed(app, event)
            if ~isempty(app.yint_a)
                findIntersects(app)
                % fit graph
                fitGraph(app);
                % display graph
                imagesc(app.ImageAxes, app.im2);
                hold(app.ImageAxes, 'on')
                plot(app.ImageAxes, app.outGraph, 'XData', app.intshifted(:,2), 'YData', app.intshifted(:,1), 'LineWidth', 2)
                hold(app.ImageAxes, 'off')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeDownFcn, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.aaseperationpixEditFieldLabel = uilabel(app.LeftPanel);
            app.aaseperationpixEditFieldLabel.Text = 'a-a seperation (pix)';
            app.aSepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.aSepEditField.ValueChangedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.aSepEditField.Value = 10;
            app.bbseperationpixEditFieldLabel = uilabel(app.LeftPanel);
            app.bbseperationpixEditFieldLabel.Text = 'b-b seperation (pix)';
            app.bSepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bSepEditField.ValueChangedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.bSepEditField.Value = 10;
            app.rightEditFieldLabel = uilabel(app.LeftPanel);
            app.rightEditFieldLabel.Text = 'right (%)';
            app.b_right = uieditfield(app.LeftPanel, 'numeric');
            app.b_right.Limits = [-100 100];
            app.b_right.ValueChangedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.GetNewStartingVectorsButton = uibutton(app.LeftPanel, 'push');
            app.GetNewStartingVectorsButton.ButtonPushedFcn = createCallbackFcn(app, @GetNewStartingVectorsButtonPushed, true);
            app.GetNewStartingVectorsButton.Text = 'Get New Starting Vectors';
            app.ShowavectorsblueCheckBox = uicheckbox(app.LeftPanel);
            app.ShowavectorsblueCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotVectors, true);
            app.ShowavectorsblueCheckBox.Text = 'Show a vectors (blue)';
            app.ShowavectorsblueCheckBox.Value = true;
            app.ShowbvectorsredCheckBox = uicheckbox(app.LeftPanel);
            app.ShowbvectorsredCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotVectors, true);
            app.ShowbvectorsredCheckBox.Text = 'Show b vectors  (red)';
            app.ShowbvectorsredCheckBox.Value = true;
            app.UpdatePlotButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.ShowvectorsoffsetCheckBox = uicheckbox(app.LeftPanel);
            app.ShowvectorsoffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotVectors, true);
            app.ShowvectorsoffsetCheckBox.Text = 'Show vectors offset';
            app.leftEditFieldLabel = uilabel(app.LeftPanel);
            app.leftEditFieldLabel.Text = 'left (%)';
            app.b_left = uieditfield(app.LeftPanel, 'numeric');
            app.b_left.Limits = [-100 100];
            app.b_left.ValueChangedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.bnonlinearityLabel = uilabel(app.LeftPanel);
            app.bnonlinearityLabel.Text = 'b nonlinearity';
            app.rightEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.rightEditFieldLabel_2.Text = 'right (%)';
            app.a_right = uieditfield(app.LeftPanel, 'numeric');
            app.a_right.Limits = [-100 100];
            app.a_right.ValueChangedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.anonlinearityLabel = uilabel(app.LeftPanel);
            app.anonlinearityLabel.Text = 'a nonlinearity';
            app.leftEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.leftEditFieldLabel_2.Text = 'left (%)';
            app.a_left = uieditfield(app.LeftPanel, 'numeric');
            app.a_left.Limits = [-100 100];
            app.a_left.ValueChangedFcn = createCallbackFcn(app, @recalculateVectors, true);
            app.FitGridButton = uibutton(app.LeftPanel, 'push');
            app.FitGridButton.ButtonPushedFcn = createCallbackFcn(app, @FitGridButtonPushed, true);
            app.FitGridButton.Text = 'Fit Grid';
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageAxes = uiaxes(app.RightPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.FinishandCloseButton = uibutton(app.RightPanel, 'push');
            app.FinishandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeDownFcn, true);
            app.FinishandCloseButton.Text = 'Finish and Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unitCellOverlay1_4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end